classdef EMTcode_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMTModelUIFigure              matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        CellOrganizationTab           matlab.ui.container.Tab
        SettingupacoculturemodelifselectedPanel  matlab.ui.container.Panel
        FibroblastsEditField          matlab.ui.control.EditField
        FibroblastsEditFieldLabel     matlab.ui.control.Label
        TimePanel                     matlab.ui.container.Panel
        TimeIntervalhrEditField       matlab.ui.control.NumericEditField
        TimeIntervalhrEditFieldLabel  matlab.ui.control.Label
        FinalTimehrEditField          matlab.ui.control.NumericEditField
        FinalTimehrEditFieldLabel     matlab.ui.control.Label
        Panel2_4                      matlab.ui.container.Panel
        SpheroidRadiusEditField       matlab.ui.control.NumericEditField
        SpheroidRadiusEditFieldLabel  matlab.ui.control.Label
        MorphologyDropDown            matlab.ui.control.DropDown
        MorphologyDropDownLabel       matlab.ui.control.Label
        CellTypesDropDown             matlab.ui.control.DropDown
        CellTypesDropDownLabel        matlab.ui.control.Label
        EnvironmentTab                matlab.ui.container.Tab
        InhibitingECMRemodelingPanel  matlab.ui.container.Panel
        fullinhibition1noinhibitionLabel  matlab.ui.control.Label
        InhibitionRateEditField       matlab.ui.control.NumericEditField
        InhibitionRateEditFieldLabel  matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        Panel2_2                      matlab.ui.container.Panel
        InitialDtgfbCoefficientEditField  matlab.ui.control.NumericEditField
        InitialDtgfbCoefficientEditFieldLabel  matlab.ui.control.Label
        Panel2                        matlab.ui.container.Panel
        ExogeneousTGFBEditField       matlab.ui.control.NumericEditField
        ExogeneousTGFBEditFieldLabel  matlab.ui.control.Label
        TGFBSignalingDropDown         matlab.ui.control.DropDown
        TGFBSignalingDropDown_2Label  matlab.ui.control.Label
        SignalingIntervalhrEditField  matlab.ui.control.NumericEditField
        SignalingIntervalhrEditFieldLabel  matlab.ui.control.Label
        IntracellularTGFB1Tab         matlab.ui.container.Tab
        ResetButton                   matlab.ui.control.Button
        TGFBODEInitialConcentrationsPanel  matlab.ui.container.Panel
        NCadEditField                 matlab.ui.control.NumericEditField
        NCadEditFieldLabel            matlab.ui.control.Label
        ECadEditField                 matlab.ui.control.NumericEditField
        ECadEditFieldLabel            matlab.ui.control.Label
        miR200EditField               matlab.ui.control.NumericEditField
        miR200EditFieldLabel          matlab.ui.control.Label
        Zeb1EditField                 matlab.ui.control.NumericEditField
        Zeb1EditFieldLabel            matlab.ui.control.Label
        zeb1_tEditField               matlab.ui.control.NumericEditField
        zeb1_tEditFieldLabel          matlab.ui.control.Label
        miR34EditField                matlab.ui.control.NumericEditField
        miR34EditFieldLabel           matlab.ui.control.Label
        Snail1EditField               matlab.ui.control.NumericEditField
        Snail1EditFieldLabel          matlab.ui.control.Label
        snail_tEditField              matlab.ui.control.NumericEditField
        snail_tEditFieldLabel         matlab.ui.control.Label
        HillEquationPanel             matlab.ui.container.Panel
        J_2eEditField                 matlab.ui.control.NumericEditField
        J_2eEditFieldLabel            matlab.ui.control.Label
        nnEditField                   matlab.ui.control.NumericEditField
        nnEditFieldLabel              matlab.ui.control.Label
        J_2nEditField                 matlab.ui.control.NumericEditField
        J_2nEditField_17Label         matlab.ui.control.Label
        J_1nEditField                 matlab.ui.control.NumericEditField
        J_1nEditFieldLabel            matlab.ui.control.Label
        J_22EditField                 matlab.ui.control.NumericEditField
        J_22EditFieldLabel            matlab.ui.control.Label
        J_12EditField                 matlab.ui.control.NumericEditField
        J_12EditFieldLabel            matlab.ui.control.Label
        J_ZEditField                  matlab.ui.control.NumericEditField
        J_ZEditFieldLabel             matlab.ui.control.Label
        J_1eEditField                 matlab.ui.control.NumericEditField
        J_zEditField                  matlab.ui.control.NumericEditField
        J_1eEditFieldLabel            matlab.ui.control.Label
        J_zEditFieldLabel             matlab.ui.control.Label
        J_23EditField                 matlab.ui.control.NumericEditField
        J_23EditFieldLabel            matlab.ui.control.Label
        J_13EditField                 matlab.ui.control.NumericEditField
        J_13Label                     matlab.ui.control.Label
        J_SEditField                  matlab.ui.control.NumericEditField
        J_SEditFieldLabel             matlab.ui.control.Label
        J_sEditField                  matlab.ui.control.NumericEditField
        J_sEditFieldLabel             matlab.ui.control.Label
        J_TEditField                  matlab.ui.control.NumericEditField
        J_TEditFieldLabel             matlab.ui.control.Label
        IntracellularTGFBTab          matlab.ui.container.Tab
        ReactionRatesKPanel           matlab.ui.container.Panel
        ResetButton_2                 matlab.ui.control.Button
        k_dnEditField                 matlab.ui.control.NumericEditField
        k_dnEditField_2Label          matlab.ui.control.Label
        k_sEditField                  matlab.ui.control.NumericEditField
        k_sEditField_2Label           matlab.ui.control.Label
        k_e1EditField                 matlab.ui.control.NumericEditField
        k_e1EditField_2Label          matlab.ui.control.Label
        k_ZEditField                  matlab.ui.control.NumericEditField
        k_ZEditField_2Label           matlab.ui.control.Label
        k_3EditField                  matlab.ui.control.NumericEditField
        k_3EditField_2Label           matlab.ui.control.Label
        k_n2EditField                 matlab.ui.control.NumericEditField
        k_n2EditField_2Label          matlab.ui.control.Label
        k_0sEditField                 matlab.ui.control.NumericEditField
        k_0sEditField_2Label          matlab.ui.control.Label
        k_d2EditField                 matlab.ui.control.NumericEditField
        k_d2EditField_2Label          matlab.ui.control.Label
        k_dzEditField                 matlab.ui.control.NumericEditField
        k_dzEditField_2Label          matlab.ui.control.Label
        k_03EditField                 matlab.ui.control.NumericEditField
        k_03EditField_2Label          matlab.ui.control.Label
        k_n1EditField                 matlab.ui.control.NumericEditField
        k_n1EditField_2Label          matlab.ui.control.Label
        k_dTEditField                 matlab.ui.control.NumericEditField
        k_dTEditField_2Label          matlab.ui.control.Label
        k_2EditField                  matlab.ui.control.NumericEditField
        k_2EditField_2Label           matlab.ui.control.Label
        k_zEditField                  matlab.ui.control.NumericEditField
        k_zEditField_2Label           matlab.ui.control.Label
        k_dSEditField                 matlab.ui.control.NumericEditField
        k_dSEditField_2Label          matlab.ui.control.Label
        k_deEditField_2Label          matlab.ui.control.Label
        k_deEditField                 matlab.ui.control.NumericEditField
        k_TEditField                  matlab.ui.control.NumericEditField
        k_TEditField_2Label           matlab.ui.control.Label
        k_02EditField                 matlab.ui.control.NumericEditField
        k_02EditField_2Label          matlab.ui.control.Label
        k_0zEditField                 matlab.ui.control.NumericEditField
        k_0zEditField_2Label          matlab.ui.control.Label
        k_SEditField                  matlab.ui.control.NumericEditField
        k_SEditField_2Label           matlab.ui.control.Label
        k_e2EditField                 matlab.ui.control.NumericEditField
        k_e2EditField_2Label          matlab.ui.control.Label
        k_dZEditField                 matlab.ui.control.NumericEditField
        k_dZEditField_2Label          matlab.ui.control.Label
        k_d3EditField                 matlab.ui.control.NumericEditField
        k_d3EditField_2Label          matlab.ui.control.Label
        k_dsEditField                 matlab.ui.control.NumericEditField
        k_dsEditField_2Label          matlab.ui.control.Label
        k_0TEditField                 matlab.ui.control.NumericEditField
        k_0TEditField_2Label          matlab.ui.control.Label
        SavingDataTab                 matlab.ui.container.Tab
        TosaveasagifmakesurethisischeckedbeforehittingRUNLabel  matlab.ui.control.Label
        SavingGraphOutputsPanel       matlab.ui.container.Panel
        SaveasButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton                    matlab.ui.control.RadioButton
        gifButton                     matlab.ui.control.RadioButton
        tifButton                     matlab.ui.control.RadioButton
        jpgButton                     matlab.ui.control.RadioButton
        ExportImageButton             matlab.ui.control.Button
        ImageNameEditField            matlab.ui.control.EditField
        ImageNameLabel                matlab.ui.control.Label
        SavingTableOutputsPanel       matlab.ui.container.Panel
        ExportDataButton              matlab.ui.control.Button
        IncludefiletagxlsxorcsvLabel  matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        AppendtoexistingdatasheetCheckBox  matlab.ui.control.CheckBox
        RepeatRunsPanel               matlab.ui.container.Panel
        RunsEditField                 matlab.ui.control.NumericEditField
        RunsEditFieldLabel            matlab.ui.control.Label
        DoyouwanttorunmultiplesimulationsusingthesameparametersCheckBox  matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        PreviewButton                 matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        DataSelectionLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        RunCountLabel                 matlab.ui.control.Label
        DMulticellularABMModelofEMTLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        GraphsTab                     matlab.ui.container.Tab
        ImagePanel                    matlab.ui.container.Panel
        RunTimeLabel                  matlab.ui.control.Label
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        RunsTab                       matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        CellOutputsTab                matlab.ui.container.Tab
        UITable2                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % Declare properties
        CellState     = DefineCells();
        Param         = RunParam();
        OutputResults = [];    
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Utility function to initialize a plot
            
            % Initially assume that all data will be displayed and then,
            %  subsequently, filter the data based on the controls            
            TGFB            = 0;

            Dtgfb0          = 38;
            Dchange         = 1;

            Tfinal          = 1; 
            t               = 1;

           % Setting up Exogeneous TGFB1 variable
            n      = app.Param.n;            
            Ctgfb  = TGFB*ones(n,n,n); % TGFB concentration gradient
            
            % Setting up Diffusion Coefficients/ ECM representation
            Dtgfb  = Dtgfb0*ones(n,n,n);  % Diffusion values matrix (nxnxn)
                       
            [app.CellState, Ctgfb, Dtgfb, Cstate] = ...
                CellMorphology(app.CellState, app.Param, Ctgfb, Dtgfb);
        
            plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb,...
                TGFB, Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3,...
                app.UIAxes4, app.UIAxes5, app.UIAxes6);
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EMTModelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {694, 694};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PreviewButton
        function PreviewBtnPushed(app, event)
% Update Graphs based on changes to Variables
app.CellState   = DefineCells(); % Reset workspace

% Updating Intracellular Signaling Dynamics

%     Initial Conditions (X)
app.Param.conc0=[app.snail_tEditField.Value, app.Snail1EditField.Value, ...
    app.miR34EditField.Value, app.zeb1_tEditField.Value, ...
    app.Zeb1EditField.Value, app.miR200EditField.Value, ...
    app.ECadEditField.Value, app.NCadEditField.Value];
    % (uM)  [snail, SNAIL, miRNA34, zeb1, Zeb1, miRNA200, ECAD, NCAD]

%     Hill Coefficients (J)
app.Param.HillCoeff_J = [app.J_TEditField.Value, app.J_sEditField.Value, ...
    app.J_SEditField.Value, app.J_13EditField.Value, ...
    app.J_23EditField.Value, app.J_zEditField.Value, ...
    app.J_ZEditField.Value, app.J_12EditField.Value, ...
    app.J_22EditField.Value, app.J_1eEditField.Value, ...
    app.J_2eEditField.Value, app.J_1nEditField.Value, ...
    app.J_2nEditField.Value]; 
    % (uM) [JT, Js, JS, J13, J23, Jz, JZ, J12, J22, J1e, J2e, J1n, J2n] 

app.Param.HillCoeff_n = app.nnEditField.Value.*[1, 1, 1, 1, 1];    
    % [nnt, nns, nnz, nnr2, nnr3]

%     Reaction Rates (K)
app.Param.Krates = [app.k_0TEditField.Value, app.k_TEditField.Value, ...
    app.k_dTEditField.Value, app.k_0sEditField.Value, ...
    app.k_sEditField.Value, app.k_dsEditField.Value, ...
    app.k_SEditField.Value, app.k_dSEditField.Value, ...
    app.k_03EditField.Value, app.k_3EditField.Value, ...
    app.k_d3EditField.Value, app.k_0zEditField.Value, ...
    app.k_zEditField.Value, app.k_dzEditField.Value, ...
    app.k_ZEditField.Value, app.k_dZEditField.Value, ...
    app.k_02EditField.Value, app.k_2EditField.Value, ...
    app.k_d2EditField.Value, app.k_e1EditField.Value, ...
    app.k_e2EditField.Value, app.k_deEditField.Value, ...
    app.k_n1EditField.Value, app.k_n2EditField.Value, ...
    app.k_dnEditField.Value];

%   k = [k0T, kT, kdT, k0s, ks, kds, kS, kdS, 
%       k03, k3, kd3, k0z, kz, kdz, kZ, kdZ, 
%       k02, k2, kd2, ke1, ke2, kde, kn1, kn2,
%        kdn]


% If changes in spheroid morphology were done:
app.Param.Crado         = app.SpheroidRadiusEditField.Value;
app.Param.Cradi         = app.Param.Crado - 0.5;

app.Param.TGFB          = app.ExogeneousTGFBEditField.Value;
app.Param.TGFBSignalType  = app.TGFBSignalingDropDown.Value;
app.Param.TGFBSignalInt   = app.SignalingIntervalhrEditField.Value;

app.Param.Dtgfb0          = app.InitialDtgfbCoefficientEditField.Value;
            
app.Param.CellTypes       = app.CellTypesDropDown.Value;
app.Param.Morphology      = app.MorphologyDropDown.Value;
app.Param.FibroblastCount = str2num(app.FibroblastsEditField.Value);
app.Param.Tfinal          = app.FinalTimehrEditField.Value; 
app.Param.Tint            = app.TimeIntervalhrEditField.Value;

t                         = 1;

% Setting up Exogeneous TGFB1 variable
n               = app.Param.n;            
Ctgfb           = app.Param.TGFB*ones(n,n,n);  % TGFB concentration gradient

% Setting up Diffusion Coefficients/ ECM representation
Dtgfb           = app.Param.Dtgfb0*ones(n,n,n); 
        % Diffusion values matrix (nxnxn)

[app.CellState, Ctgfb, Dtgfb, Cstate] = CellMorphology(app.CellState,...
    app.Param, Ctgfb, Dtgfb);


plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
    app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3,...
    app.UIAxes4, app.UIAxes5, app.UIAxes6);

app.RunTimeLabel.Text = ['Run Time: ', num2str(t-1), ' hr'];
        end

        % Button pushed function: RunButton
        function RunBtnPushed(app, event)
% Checking Button Inputs:

[app.Param] = RunParam; % Reset params if previously changed

% Do you want to run multiple simulations using the same parameters?
if app.DoyouwanttorunmultiplesimulationsusingthesameparametersCheckBox.Value == 1 || app.RunsEditField.Value > 1
    Nruns = app.RunsEditField.Value;
    app.Param.Nruns = Nruns;
else
    app.Param.Nruns = 1;
end

DataOutput = [];
tic

% Updating Intracellular Signaling Dynamics

%     Initial Conditions (X)
app.Param.conc0=[app.snail_tEditField.Value, app.Snail1EditField.Value, ...
    app.miR34EditField.Value, app.zeb1_tEditField.Value, ...
    app.Zeb1EditField.Value, app.miR200EditField.Value, ...
    app.ECadEditField.Value, app.NCadEditField.Value];
    % (uM)  [snail, SNAIL, miRNA34, zeb1, Zeb1, miRNA200, ECAD, NCAD]

%     Hill Coefficients (J)
app.Param.HillCoeff_J = [app.J_TEditField.Value, app.J_sEditField.Value, ...
    app.J_SEditField.Value, app.J_13EditField.Value, ...
    app.J_23EditField.Value, app.J_zEditField.Value, ...
    app.J_ZEditField.Value, app.J_12EditField.Value, ...
    app.J_22EditField.Value, app.J_1eEditField.Value, ...
    app.J_2eEditField.Value, app.J_1nEditField.Value, ...
    app.J_2nEditField.Value]; 
    % (uM) [JT, Js, JS, J13, J23, Jz, JZ, J12, J22, J1e, J2e, J1n, J2n] 

app.Param.HillCoeff_n = app.nnEditField.Value.*[1, 1, 1, 1, 1];    
    % [nnt, nns, nnz, nnr2, nnr3]

%     Reaction Rates (K)
app.Param.Krates = [app.k_0TEditField.Value, app.k_TEditField.Value, ...
    app.k_dTEditField.Value, app.k_0sEditField.Value, ...
    app.k_sEditField.Value, app.k_dsEditField.Value, ...
    app.k_SEditField.Value, app.k_dSEditField.Value, ...
    app.k_03EditField.Value, app.k_3EditField.Value, ...
    app.k_d3EditField.Value, app.k_0zEditField.Value, ...
    app.k_zEditField.Value, app.k_dzEditField.Value, ...
    app.k_ZEditField.Value, app.k_dZEditField.Value, ...
    app.k_02EditField.Value, app.k_2EditField.Value, ...
    app.k_d2EditField.Value, app.k_e1EditField.Value, ...
    app.k_e2EditField.Value, app.k_deEditField.Value, ...
    app.k_n1EditField.Value, app.k_n2EditField.Value, ...
    app.k_dnEditField.Value];

%   k = [k0T, kT, kdT, k0s, ks, kds, kS, kdS, 
%       k03, k3, kd3, k0z, kz, kdz, kZ, kdZ, 
%       k02, k2, kd2, ke1, ke2, kde, kn1, kn2,
%        kdn]


% Starting the run:
for incr = 1:app.Param.Nruns

% Update Graphs based on changes to Variables

app.CellState   = DefineCells(); % Reset workspace

app.Param.Crado         = app.SpheroidRadiusEditField.Value;
app.Param.Cradi         = app.Param.Crado - 0.5;

app.Param.TGFB            = app.ExogeneousTGFBEditField.Value;
app.Param.TGFBSignalType  = app.TGFBSignalingDropDown.Value;
app.Param.TGFBSignalInt   = app.SignalingIntervalhrEditField.Value;

app.Param.Dtgfb0          = app.InitialDtgfbCoefficientEditField.Value;
app.Param.EMTThresh       = app.InhibitionRateEditField.Value;

app.Param.DtgfbInhibitionMethod = app.MethodDropDown.Items(app.MethodDropDown.Value);

if app.MethodDropDown.Value == 2
    app.Param.DThresh = (app.Param.EMTThresh).*app.Param.DThresh; 
    % EMT-dependent changes in ECM remodeling
end

app.Param.CellTypes       = app.CellTypesDropDown.Value;
app.Param.Morphology      = app.MorphologyDropDown.Value;
app.Param.FibroblastCount = str2double(app.FibroblastsEditField.Value);

app.Param.Tfinal          = app.FinalTimehrEditField.Value; 
app.Param.Tint            = app.TimeIntervalhrEditField.Value;


t               = 1;
dt              = 1; 

% Setting up Exogeneous TGFB1 variable
n       = app.Param.n;            
Ctgfb   = app.Param.TGFB*ones(n,n,n);  % TGFB concentration gradient

% Setting up Diffusion Coefficients/ ECM representation
Dtgfb   = app.Param.Dtgfb0*ones(n,n,n);      % Diffusion values matrix (nxnxn)

% Create Cells + Spheroid
[app.CellState, Ctgfb, Dtgfb, Cstate] = CellMorphology(app.CellState,...
    app.Param, Ctgfb, Dtgfb);

C0tgfb  = Ctgfb; % initial TGFB distribution

if app.Param.Nruns == 1
    plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
        app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3, ...
        app.UIAxes4, app.UIAxes5, app.UIAxes6);
    
    if app.gifButton.Value == 1
       
       ImageName = app.ImageNameEditField.Value;

       if isempty(app.ImageNameEditField.Value)

           uialert(app.EMTModelUIFigure, ...
               "Image Name not defined, set to Default", "Saving Image Output");
           break;

       else

        ImageTag = '.gif';
        ImageName = [ImageName, ImageTag];
        exportgraphics(app.ImagePanel, ImageName);
       end

    end
end

% Start time steps

for t = 2:dt:app.Param.Tfinal

    switch app.Param.TGFBSignalType

        case 'Removed'

           if t > app.Param.TGFBSignalInt
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = 0;
                            end
                        end
                    end
                end
            end
                        
        case 'Pulsed'

           if rem(t, app.Param.TGFBSignalInt*2) == 0
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = Ctgfb(i,j,k) + app.Param.TGFB;
                            end
                        end
                    end
               end

           elseif rem(t, app.Param.TGFBSignalInt) == 0 && rem(t, app.Param.TGFBSignalInt*2)~= 0

               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = 0;
                            end
                        end
                    end
               end
           end
    
        case 'Increasing Interval'

           if rem(t, app.Param.TGFBSignalInt) == 0
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = Ctgfb(i,j,k) + app.Param.TGFB;
                            end
                        end
                    end
               end
           end
           
        case 'Constant'
            
     end

    Cmoved    = zeros(n,n,n); % monitor movements of cells + update in system
    
    [app.CellState, Cstate, Ctgfb, Dtgfb] = ...
        EMTStateChange(app.CellState, app.Param, Cstate, Ctgfb, ...
        Dtgfb, t, app.Param.Tfinal, dt);
    
    % Update Cell Proliferation + Migration
    flag     = 0; 
    v        = 1; 
    numCells = length(app.CellState.state);
    
    while flag == 0
        i = app.CellState.Position(v,4); 
        j = app.CellState.Position(v,5);  
        k = app.CellState.Position(v,6); 
        
        if Cmoved(i,j,k) == 0 && app.CellState.state(v) > 0

            [app.CellState, Cstate, Cmoved, Ctgfb, Dtgfb] = ...
                CellBehavior(app.CellState, app.Param, Cstate, ...
                i, j, k, v, Ctgfb, t, Cmoved, Dtgfb);  
        end
        
        v        = v + 1; 
        numCells = length(app.CellState.state);
        
        if v >= numCells
            flag = 1;
        end    
    end
   
    % Update TGFB Diffusion
    Ctgfb    = TGFBDiffusion(app.Param, Ctgfb, app.Param.TGFB, Dtgfb);
    
    % Update Population Cell Concentrations + Values
    eInd     = find(app.CellState.state < 4);

    app.CellState.AvgEcad(t)  = mean(app.CellState.conc(eInd, 7));
    app.CellState.AvgNcad(t)  = mean(app.CellState.Ncad(eInd,2));
    
    app.CellState.AvgSnail(t) = mean(app.CellState.conc(eInd, 2)); 
    app.CellState.AvgZeb1(t)  = mean(app.CellState.conc(eInd, 5));
    
    app.CellState.AvgR200(t)  = mean(app.CellState.conc(eInd, 6));
    app.CellState.AvgR34(t)   = mean(app.CellState.conc(eInd, 3));

    app.CellState.AvgTGFB(t)      = mean(app.CellState.Ctgfb(eInd));
    app.CellState.AvgDtgfb(t)     = mean(app.CellState.Dcell(eInd));
    

    % Plotting Data:
    if app.Param.Nruns == 1 && rem(t, app.Param.Tint) == 0
        plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
            app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3, ...
            app.UIAxes4, app.UIAxes5, app.UIAxes6);

        app.RunTimeLabel.Text = ['Run Time: ', num2str(t-1), ' hr'];
        
        % Saving Possible Video Frames
        if app.gifButton.Value == 1

            exportgraphics(app.ImagePanel, ImageName, 'Append', true);
        
        end

        pause(0.05);   
    end

end

% Showing Individual Cell Data:
CellTable = table((1:length(app.CellState.state))', ...
    app.CellState.state', app.CellState.conc(:,7), ...
    app.CellState.Ncad(:, 2), app.CellState.conc(:,2), ...
    app.CellState.conc(:,5), app.CellState.conc(:,3), ...
    app.CellState.conc(:,6), app.CellState.Ctgfb', ...
    app.CellState.Dcell', app.CellState.TimesMoved');

app.UITable2.Data = CellTable;

% showing summarized population data:
DataOutput        = SaveExcelResults(app.CellState, app.Param, Cstate, ...
    Ctgfb, Dtgfb, incr, DataOutput);

app.OutputResults = [struct2table(DataOutput)];

app.UITable.Data = app.OutputResults;

app.RunCountLabel.Text = ['Run Count: ', num2str(incr)];

end

app.Param.totRunTime = toc;

        end

        % Value changed function: AppendtoexistingdatasheetCheckBox
        function AppendBtnChecked(app, event)
            
            [FileName, FilePath] = uigetfile('.xlsx', '.csv');

            app.FileNameEditField.Value = FileName;
        end

        % Button pushed function: ExportDataButton
        function SaveDataOutput(app, event)

            FileName = app.FileNameEditField.Value;
            if app.AppendtoexistingdatasheetCheckBox.Value == 0
                XFileName = FileName;

                writetable(app.OutputResults, XFileName, ...
                    'Sheet', 'Outputs', 'Range', 'A1');
                        
         ParamT = struct2table(app.Param);
         ParamNames = {'# voxels/edge','Cell Size', 'Outer Radii',...
         'Inner Radii', 'Dtgfb Cell Value', ...         
         'Dtgfb Inhibition (EMTThresh)', ...
         'EMT-Dependent local ECM remodeling (DThresh)', ...
         'Ncad Max', 'Proliferation Threshold', 'Prob Thresholds', ...
         'Epithelial ODE Conc(0)', 'Mesenchymal ODE Conc(0)', ...
        'Nruns', 'TGFB exogeneous Conc(0)', 'TGFB Signaling Dynamics', ...
         'TGFB Signaling Interval', 'Dtgfb Extracellular Value', ...
         'Dtgfb Inhibition Method', 'Culture Model', 'Morphology', ...
         '# Fibroblasts', 'Tfinal', 'Time interval', ...
         'Processing Speed (s)'};

                sheetParam = 'Run Parameters';
                writetable([ParamT], XFileName, ...
                    'Sheet', sheetParam, 'Range', 'A1');


            elseif app.AppendtoexistingdatasheetCheckBox.Value == 1
                AppendBtnChecked(app, event);
                XFileName = FileName;
                writetable(app.OutputResults, XFileName, 'append');

            end

        end

        % Button pushed function: ExportImageButton
        function saveImageOutput(app, event)
           % Saving end image output
           
           ImageName = app.ImageNameEditField.Value;
           

           if isempty(app.ImageNameEditField.Value)
               uialert(app.EMTModelUIFigure, "Image Name not defined", "Saving Image Output");
           
           end


           if app.NoneButton.Value == 1
               uialert(app.EMTModelUIFigure, "Please choose an image extension", "Saving Image Output");
               return

           elseif app.tifButton.Value == 1
              ImageTag = '.tif';

           elseif app.jpgButton.Value == 1
               ImageTag = '.jpg';

           end

              ImageName = [ImageName, ImageTag];

              exportgraphics(app.ImagePanel, ImageName);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        
        % Reset for Intracellular TGFB Signaling Tab 1
        app.Param = RunParam;
        
        % Initial Concentrations: 
        app.snail_tEditField.Value  = app.Param.conc0(1);    
        app.Snail1EditField.Value   = app.Param.conc0(2); 
        app.miR34EditField.Value    = app.Param.conc0(3); 
        app.zeb1_tEditField.Value   = app.Param.conc0(4); 
        app.Zeb1EditField.Value     = app.Param.conc0(5); 
        app.miR200EditField.Value   = app.Param.conc0(6); 
        app.ECadEditField.Value     = app.Param.conc0(7); 
        app.NCadEditField.Value     = app.Param.conc0(8); 

        % Hill Coefficients:
        app.J_TEditField.Value      = app.Param.HillCoeff_J(1);
        app.J_sEditField.Value      = app.Param.HillCoeff_J(2);
        app.J_SEditField.Value      = app.Param.HillCoeff_J(3);
        app.J_13EditField.Value     = app.Param.HillCoeff_J(4);
        app.J_23EditField.Value     = app.Param.HillCoeff_J(5);
        app.J_zEditField.Value      = app.Param.HillCoeff_J(6);
        app.J_ZEditField.Value      = app.Param.HillCoeff_J(7);
        app.J_12EditField.Value     = app.Param.HillCoeff_J(8);
        app.J_22EditField.Value     = app.Param.HillCoeff_J(9);
        app.J_1eEditField.Value     = app.Param.HillCoeff_J(10);
        app.J_2eEditField.Value     = app.Param.HillCoeff_J(11);
        app.J_1nEditField.Value     = app.Param.HillCoeff_J(12);
        app.J_2nEditField.Value     = app.Param.HillCoeff_J(13);
        app.nnEditField.Value       = app.Param.HillCoeff_n(1);

        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
        % Reset for Intracellular TGFB Signaling Tab 2 
        app.Param = RunParam;

        %   Reaction Rates:
        app.k_0TEditField.Value     = app.Param.Krates(1);
        app.k_TEditField.Value      = app.Param.Krates(2);
        app.k_dTEditField.Value     = app.Param.Krates(3);
        app.k_0sEditField.Value     = app.Param.Krates(4);
        app.k_sEditField.Value      = app.Param.Krates(5);
        app.k_dsEditField.Value     = app.Param.Krates(6);
        app.k_SEditField.Value      = app.Param.Krates(7);
        app.k_dSEditField.Value     = app.Param.Krates(8);
        app.k_03EditField.Value     = app.Param.Krates(9);
        app.k_3EditField.Value      = app.Param.Krates(10);
        app.k_d3EditField.Value     = app.Param.Krates(11);
        app.k_0zEditField.Value     = app.Param.Krates(12);
        app.k_zEditField.Value      = app.Param.Krates(13);
        app.k_dzEditField.Value     = app.Param.Krates(14);
        app.k_ZEditField.Value      = app.Param.Krates(15);
        app.k_dZEditField.Value     = app.Param.Krates(16);
        app.k_02EditField.Value     = app.Param.Krates(17);
        app.k_2EditField.Value      = app.Param.Krates(18);
        app.k_d2EditField.Value     = app.Param.Krates(19);
        app.k_e1EditField.Value     = app.Param.Krates(20);
        app.k_e2EditField.Value     = app.Param.Krates(21);
        app.k_deEditField.Value     = app.Param.Krates(22);
        app.k_n1EditField.Value     = app.Param.Krates(23);
        app.k_n2EditField.Value     = app.Param.Krates(24);
        app.k_dnEditField.Value     = app.Param.Krates(25);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMTModelUIFigure = uifigure('Visible', 'off');
            app.EMTModelUIFigure.Name = 'EMTCode_App';
            app.EMTModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EMTModelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunButton.Text = 'Run';
            app.PreviewButton = uibutton(app.LeftPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewBtnPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'cellwitheyes.JPG';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.CellOrganizationTab = uitab(app.TabGroup2);
            app.CellOrganizationTab.Title = 'Cell Organization';
            app.Panel2_4 = uipanel(app.CellOrganizationTab);
            app.Panel2_4.Title = 'Cell Morphology:';
            app.CellTypesDropDownLabel = uilabel(app.Panel2_4);
            app.CellTypesDropDownLabel.Text = 'Cell Types';
            app.CellTypesDropDown = uidropdown(app.Panel2_4);
            app.CellTypesDropDown.Items = {'Epithelial Only', 'Embedded', 'Overlay'};
            app.CellTypesDropDown.Value = 'Epithelial Only';
            app.MorphologyDropDownLabel = uilabel(app.Panel2_4);
            app.MorphologyDropDownLabel.Text = 'Morphology';
            app.MorphologyDropDown = uidropdown(app.Panel2_4);
            app.MorphologyDropDown.Items = {'Spheroid', 'Straight Tube (Vertical)', 'Straight Tube (Horizontal)', 'Curved Tube'};
            app.MorphologyDropDown.ItemsData = [0 1 2 3];
            app.MorphologyDropDown.Value = 0;
            app.SpheroidRadiusEditFieldLabel = uilabel(app.Panel2_4);
            app.SpheroidRadiusEditFieldLabel.Text = 'Spheroid Radius';
            app.SpheroidRadiusEditField = uieditfield(app.Panel2_4, 'numeric');
            app.SpheroidRadiusEditField.Limits = [0 30];
            app.SpheroidRadiusEditField.Placeholder = '2.5';
            app.SpheroidRadiusEditField.Value = 2.5;
            app.TimePanel = uipanel(app.CellOrganizationTab);
            app.TimePanel.Title = 'Time';
            app.FinalTimehrEditFieldLabel = uilabel(app.TimePanel);
            app.FinalTimehrEditFieldLabel.Text = 'Final Time (hr)';
            app.FinalTimehrEditField = uieditfield(app.TimePanel, 'numeric');
            app.FinalTimehrEditField.Limits = [1 Inf];
            app.FinalTimehrEditField.Value = 1;
            app.TimeIntervalhrEditFieldLabel = uilabel(app.TimePanel);
            app.TimeIntervalhrEditFieldLabel.Text = 'Time Interval (hr)';
            app.TimeIntervalhrEditField = uieditfield(app.TimePanel, 'numeric');
            app.TimeIntervalhrEditField.Limits = [1 Inf];
            app.TimeIntervalhrEditField.Value = 1;
            app.SettingupacoculturemodelifselectedPanel = uipanel(app.CellOrganizationTab);
            app.SettingupacoculturemodelifselectedPanel.Title = 'Setting up a co-culture model if selected:';
            app.FibroblastsEditFieldLabel = uilabel(app.SettingupacoculturemodelifselectedPanel);
            app.FibroblastsEditFieldLabel.Text = '# Fibroblasts';
            app.FibroblastsEditField = uieditfield(app.SettingupacoculturemodelifselectedPanel, 'text');
            app.FibroblastsEditField.Placeholder = '0';
            app.FibroblastsEditField.Value = '0';
            app.EnvironmentTab = uitab(app.TabGroup2);
            app.EnvironmentTab.Title = 'Environment';
            app.Panel2 = uipanel(app.EnvironmentTab);
            app.Panel2.Title = 'Extracellular TGFB1:';
            app.SignalingIntervalhrEditFieldLabel = uilabel(app.Panel2);
            app.SignalingIntervalhrEditFieldLabel.Text = 'Signaling Interval (hr)';
            app.SignalingIntervalhrEditField = uieditfield(app.Panel2, 'numeric');
            app.SignalingIntervalhrEditField.Limits = [0 Inf];
            app.TGFBSignalingDropDown_2Label = uilabel(app.Panel2);
            app.TGFBSignalingDropDown_2Label.Text = 'TGFB Signaling';
            app.TGFBSignalingDropDown = uidropdown(app.Panel2);
            app.TGFBSignalingDropDown.Items = {'Constant', 'Removed', 'Pulsed', 'Increasing Interval'};
            app.TGFBSignalingDropDown.Value = 'Constant';
            app.ExogeneousTGFBEditFieldLabel = uilabel(app.Panel2);
            app.ExogeneousTGFBEditFieldLabel.Text = 'Exogeneous TGFB';
            app.ExogeneousTGFBEditField = uieditfield(app.Panel2, 'numeric');
            app.ExogeneousTGFBEditField.Limits = [0 10];
            app.Panel2_2 = uipanel(app.EnvironmentTab);
            app.Panel2_2.Title = 'Initializing the ECM environment:';
            app.InitialDtgfbCoefficientEditFieldLabel = uilabel(app.Panel2_2);
            app.InitialDtgfbCoefficientEditFieldLabel.Text = 'Initial Dtgfb Coefficient';
            app.InitialDtgfbCoefficientEditField = uieditfield(app.Panel2_2, 'numeric');
            app.InitialDtgfbCoefficientEditField.Limits = [0 38];
            app.InitialDtgfbCoefficientEditField.Value = 38;
            app.InhibitingECMRemodelingPanel = uipanel(app.EnvironmentTab);
            app.InhibitingECMRemodelingPanel.Title = 'Inhibiting ECM Remodeling:';
            app.MethodDropDownLabel = uilabel(app.InhibitingECMRemodelingPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.InhibitingECMRemodelingPanel);
            app.MethodDropDown.Items = {'Local (Cell only)', 'Global (No Changes)'};
            app.MethodDropDown.ItemsData = [1 2];
            app.MethodDropDown.Value = 1;
            app.InhibitionRateEditFieldLabel = uilabel(app.InhibitingECMRemodelingPanel);
            app.InhibitionRateEditFieldLabel.Text = 'Inhibition Rate';
            app.InhibitionRateEditField = uieditfield(app.InhibitingECMRemodelingPanel, 'numeric');
            app.InhibitionRateEditField.Limits = [0 1];
            app.InhibitionRateEditField.Placeholder = '1';
            app.InhibitionRateEditField.Value = 1;
            app.fullinhibition1noinhibitionLabel = uilabel(app.InhibitingECMRemodelingPanel);
            app.fullinhibition1noinhibitionLabel.Text = '0 = full inhibition 1 = no inhibition';
            app.IntracellularTGFB1Tab = uitab(app.TabGroup2);
            app.IntracellularTGFB1Tab.Title = 'Intracellular TGF-B1';
            app.HillEquationPanel = uipanel(app.IntracellularTGFB1Tab);
            app.HillEquationPanel.Title = 'Hill Equation:';
            app.J_TEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_TEditFieldLabel.Interpreter = 'tex';
            app.J_TEditFieldLabel.Text = 'J_T';
            app.J_TEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_TEditField.Value = 0.06;
            app.J_sEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_sEditFieldLabel.Interpreter = 'tex';
            app.J_sEditFieldLabel.Text = 'J_s';
            app.J_sEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_sEditField.Value = 1.6;
            app.J_SEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_SEditFieldLabel.Interpreter = 'tex';
            app.J_SEditFieldLabel.Text = 'J_S';
            app.J_SEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_SEditField.Value = 0.08;
            app.J_13Label = uilabel(app.HillEquationPanel);
            app.J_13Label.Interpreter = 'tex';
            app.J_13Label.Text = 'J_{13}';
            app.J_13EditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_13EditField.Value = 0.15;
            app.J_23EditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_23EditFieldLabel.Interpreter = 'tex';
            app.J_23EditFieldLabel.Text = 'J_{23}';
            app.J_23EditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_23EditField.Value = 0.36;
            app.J_zEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_zEditFieldLabel.Interpreter = 'tex';
            app.J_zEditFieldLabel.Text = 'J_{z}';
            app.J_1eEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_1eEditFieldLabel.Interpreter = 'tex';
            app.J_1eEditFieldLabel.Text = 'J_{1e}';
            app.J_zEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_zEditField.Value = 3.5;
            app.J_1eEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_1eEditField.Value = 0.2;
            app.J_ZEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_ZEditFieldLabel.Interpreter = 'tex';
            app.J_ZEditFieldLabel.Text = 'J_Z';
            app.J_ZEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_ZEditField.Value = 0.06;
            app.J_12EditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_12EditFieldLabel.Interpreter = 'tex';
            app.J_12EditFieldLabel.Text = 'J_{12}';
            app.J_12EditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_12EditField.Value = 5;
            app.J_22EditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_22EditFieldLabel.Interpreter = 'tex';
            app.J_22EditFieldLabel.Text = 'J_{22}';
            app.J_22EditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_22EditField.Value = 0.2;
            app.J_1nEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_1nEditFieldLabel.Interpreter = 'tex';
            app.J_1nEditFieldLabel.Text = 'J_{1n}';
            app.J_1nEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_1nEditField.Value = 0.2;
            app.J_2nEditField_17Label = uilabel(app.HillEquationPanel);
            app.J_2nEditField_17Label.Interpreter = 'tex';
            app.J_2nEditField_17Label.Text = 'J_{2n}';
            app.J_2nEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_2nEditField.Value = 0.5;
            app.nnEditFieldLabel = uilabel(app.HillEquationPanel);
            app.nnEditFieldLabel.Interpreter = 'tex';
            app.nnEditFieldLabel.Text = 'nn';
            app.nnEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.nnEditField.Value = 2;
            app.J_2eEditFieldLabel = uilabel(app.HillEquationPanel);
            app.J_2eEditFieldLabel.Interpreter = 'tex';
            app.J_2eEditFieldLabel.Text = 'J_{2e}';
            app.J_2eEditField = uieditfield(app.HillEquationPanel, 'numeric');
            app.J_2eEditField.Value = 0.5;
            app.TGFBODEInitialConcentrationsPanel = uipanel(app.IntracellularTGFB1Tab);
            app.TGFBODEInitialConcentrationsPanel.Title = 'TGFB ODE: Initial Concentrations';
            app.snail_tEditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.snail_tEditFieldLabel.Interpreter = 'tex';
            app.snail_tEditFieldLabel.Text = 'snail_t';
            app.snail_tEditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.snail_tEditField.Value = 0.01;
            app.Snail1EditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.Snail1EditFieldLabel.Interpreter = 'tex';
            app.Snail1EditFieldLabel.Text = 'Snail1';
            app.Snail1EditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.Snail1EditField.Value = 0.01;
            app.miR34EditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.miR34EditFieldLabel.Interpreter = 'tex';
            app.miR34EditFieldLabel.Text = 'miR34';
            app.miR34EditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.miR34EditField.Value = 0.38;
            app.zeb1_tEditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.zeb1_tEditFieldLabel.Interpreter = 'tex';
            app.zeb1_tEditFieldLabel.Text = 'zeb1_t';
            app.zeb1_tEditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.zeb1_tEditField.Value = 0.03;
            app.Zeb1EditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.Zeb1EditFieldLabel.Interpreter = 'tex';
            app.Zeb1EditFieldLabel.Text = 'Zeb1';
            app.Zeb1EditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.Zeb1EditField.Value = 0.01;
            app.miR200EditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.miR200EditFieldLabel.Interpreter = 'tex';
            app.miR200EditFieldLabel.Text = 'miR200';
            app.miR200EditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.miR200EditField.Value = 0.25;
            app.ECadEditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.ECadEditFieldLabel.Interpreter = 'tex';
            app.ECadEditFieldLabel.Text = 'E-Cad';
            app.ECadEditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.ECadEditField.Value = 3.2;
            app.NCadEditFieldLabel = uilabel(app.TGFBODEInitialConcentrationsPanel);
            app.NCadEditFieldLabel.Interpreter = 'tex';
            app.NCadEditFieldLabel.Text = 'N-Cad';
            app.NCadEditField = uieditfield(app.TGFBODEInitialConcentrationsPanel, 'numeric');
            app.ResetButton = uibutton(app.IntracellularTGFB1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.IntracellularTGFBTab = uitab(app.TabGroup2);
            app.IntracellularTGFBTab.Title = 'Intracellular TGFB1 2';
            app.ReactionRatesKPanel = uipanel(app.IntracellularTGFBTab);
            app.ReactionRatesKPanel.Title = 'Reaction Rates (K):';
            app.k_0TEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_0TEditField_2Label.Interpreter = 'tex';
            app.k_0TEditField_2Label.Text = 'k_{0T}';
            app.k_0TEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_0TEditField.Value = 0.0659;
            app.k_dsEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dsEditField_2Label.Interpreter = 'tex';
            app.k_dsEditField_2Label.Text = 'k_{ds}';
            app.k_dsEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dsEditField.Value = 0.09;
            app.k_d3EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_d3EditField_2Label.Interpreter = 'tex';
            app.k_d3EditField_2Label.Text = 'k_{d3}';
            app.k_d3EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_d3EditField.Value = 0.035;
            app.k_dZEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dZEditField_2Label.Interpreter = 'tex';
            app.k_dZEditField_2Label.Text = 'k_{dZ}';
            app.k_dZEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dZEditField.Value = 1.66;
            app.k_e2EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_e2EditField_2Label.Interpreter = 'tex';
            app.k_e2EditField_2Label.Text = 'k_{e2}';
            app.k_e2EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_e2EditField.Value = 0.6;
            app.k_SEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_SEditField_2Label.Interpreter = 'tex';
            app.k_SEditField_2Label.Text = 'k_S';
            app.k_SEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_SEditField.Value = 17;
            app.k_0zEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_0zEditField_2Label.Interpreter = 'tex';
            app.k_0zEditField_2Label.Text = 'k_{0z}';
            app.k_0zEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_0zEditField.Value = 0.004;
            app.k_02EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_02EditField_2Label.Interpreter = 'tex';
            app.k_02EditField_2Label.Text = 'k_{02}';
            app.k_02EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_02EditField.Value = 0.0002;
            app.k_TEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_TEditField_2Label.Interpreter = 'tex';
            app.k_TEditField_2Label.Text = 'k_T';
            app.k_TEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_TEditField.Value = 1.425;
            app.k_deEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_deEditField.Value = 0.5;
            app.k_deEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_deEditField_2Label.Interpreter = 'tex';
            app.k_deEditField_2Label.Text = 'k_{de}';
            app.k_dSEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dSEditField_2Label.Interpreter = 'tex';
            app.k_dSEditField_2Label.Text = 'k_{dS}';
            app.k_dSEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dSEditField.Value = 1.66;
            app.k_zEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_zEditField_2Label.Interpreter = 'tex';
            app.k_zEditField_2Label.Text = 'k_{z}';
            app.k_zEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_zEditField.Value = 0.06;
            app.k_2EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_2EditField_2Label.Interpreter = 'tex';
            app.k_2EditField_2Label.Text = 'k_2';
            app.k_2EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_2EditField.Value = 0.012;
            app.k_dTEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dTEditField_2Label.Interpreter = 'tex';
            app.k_dTEditField_2Label.Text = 'k_{dT}';
            app.k_dTEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dTEditField.Value = 0.086;
            app.k_n1EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_n1EditField_2Label.Interpreter = 'tex';
            app.k_n1EditField_2Label.Text = 'k_{n1}';
            app.k_n1EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_n1EditField.Value = 1;
            app.k_03EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_03EditField_2Label.Interpreter = 'tex';
            app.k_03EditField_2Label.Text = 'k_{03}';
            app.k_03EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_03EditField.Value = 0.0012;
            app.k_dzEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dzEditField_2Label.Interpreter = 'tex';
            app.k_dzEditField_2Label.Text = 'k_{dz}';
            app.k_dzEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dzEditField.Value = 0.09;
            app.k_d2EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_d2EditField_2Label.Interpreter = 'tex';
            app.k_d2EditField_2Label.Text = 'k_{d2}';
            app.k_d2EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_d2EditField.Value = 0.035;
            app.k_0sEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_0sEditField_2Label.Interpreter = 'tex';
            app.k_0sEditField_2Label.Text = 'k_{0s}';
            app.k_0sEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_0sEditField.Value = 0.0062;
            app.k_n2EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_n2EditField_2Label.Interpreter = 'tex';
            app.k_n2EditField_2Label.Text = 'k_{n2}';
            app.k_n2EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_n2EditField.Value = 0.6;
            app.k_3EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_3EditField_2Label.Interpreter = 'tex';
            app.k_3EditField_2Label.Text = 'k_{3}';
            app.k_3EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_3EditField.Value = 0.012;
            app.k_ZEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_ZEditField_2Label.Interpreter = 'tex';
            app.k_ZEditField_2Label.Text = 'k_Z';
            app.k_ZEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_ZEditField.Value = 17;
            app.k_e1EditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_e1EditField_2Label.Interpreter = 'tex';
            app.k_e1EditField_2Label.Text = 'k_{e1}';
            app.k_e1EditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_e1EditField.Value = 1;
            app.k_sEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_sEditField_2Label.Interpreter = 'tex';
            app.k_sEditField_2Label.Text = 'k_s';
            app.k_sEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_sEditField.Value = 0.03;
            app.k_dnEditField_2Label = uilabel(app.ReactionRatesKPanel);
            app.k_dnEditField_2Label.Interpreter = 'tex';
            app.k_dnEditField_2Label.Text = 'k_{dn}';
            app.k_dnEditField = uieditfield(app.ReactionRatesKPanel, 'numeric');
            app.k_dnEditField.Value = 0.5;
            app.ResetButton_2 = uibutton(app.ReactionRatesKPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.SavingDataTab = uitab(app.TabGroup2);
            app.SavingDataTab.Title = 'Saving Data';
            app.RepeatRunsPanel = uipanel(app.SavingDataTab);
            app.RepeatRunsPanel.Title = 'Repeat Runs';
            app.DoyouwanttorunmultiplesimulationsusingthesameparametersCheckBox = uicheckbox(app.RepeatRunsPanel);
            app.DoyouwanttorunmultiplesimulationsusingthesameparametersCheckBox.Text = {'   Do you want to run multiple simulations '; '    using the same parameters?'};
            app.RunsEditFieldLabel = uilabel(app.RepeatRunsPanel);
            app.RunsEditFieldLabel.Text = '# Runs';
            app.RunsEditField = uieditfield(app.RepeatRunsPanel, 'numeric');
            app.RunsEditField.Limits = [1 Inf];
            app.RunsEditField.Placeholder = '1';
            app.RunsEditField.Value = 1;
            app.SavingTableOutputsPanel = uipanel(app.SavingDataTab);
            app.SavingTableOutputsPanel.Title = 'Saving Table Outputs';
            app.AppendtoexistingdatasheetCheckBox = uicheckbox(app.SavingTableOutputsPanel);
            app.AppendtoexistingdatasheetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppendBtnChecked, true);
            app.AppendtoexistingdatasheetCheckBox.Text = 'Append to existing data sheet';
            app.FileNameEditFieldLabel = uilabel(app.SavingTableOutputsPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SavingTableOutputsPanel, 'text');
            app.IncludefiletagxlsxorcsvLabel = uilabel(app.SavingTableOutputsPanel);
            app.IncludefiletagxlsxorcsvLabel.Text = '** Include file tag (.xlsx or .csv)';
            app.ExportDataButton = uibutton(app.SavingTableOutputsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataOutput, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SavingGraphOutputsPanel = uipanel(app.SavingDataTab);
            app.SavingGraphOutputsPanel.Title = 'Saving Graph Outputs';
            app.ImageNameLabel = uilabel(app.SavingGraphOutputsPanel);
            app.ImageNameLabel.Text = 'Image Name: ';
            app.ImageNameEditField = uieditfield(app.SavingGraphOutputsPanel, 'text');
            app.ExportImageButton = uibutton(app.SavingGraphOutputsPanel, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImageOutput, true);
            app.ExportImageButton.Text = 'Export Image';
            app.SaveasButtonGroup = uibuttongroup(app.SavingGraphOutputsPanel);
            app.SaveasButtonGroup.Title = 'Save as:';
            app.jpgButton = uiradiobutton(app.SaveasButtonGroup);
            app.jpgButton.Text = '.jpg';
            app.tifButton = uiradiobutton(app.SaveasButtonGroup);
            app.tifButton.Text = '.tif';
            app.gifButton = uiradiobutton(app.SaveasButtonGroup);
            app.gifButton.Text = '.gif';
            app.NoneButton = uiradiobutton(app.SaveasButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.TosaveasagifmakesurethisischeckedbeforehittingRUNLabel = uilabel(app.SavingDataTab);
            app.TosaveasagifmakesurethisischeckedbeforehittingRUNLabel.Text = '** To save as a .gif, make sure this is checked before hitting RUN';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.ImagePanel = uipanel(app.GraphsTab);
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'Cell Morphology')
            xlabel(app.UIAxes, 'X (um)')
            ylabel(app.UIAxes, 'Y (um)')
            zlabel(app.UIAxes, 'Z (um)')
            app.UIAxes.XTick = [0 32];
            app.UIAxes2 = uiaxes(app.ImagePanel);
            title(app.UIAxes2, 'EMT Population')
            xlabel(app.UIAxes2, 'Time (hr)')
            app.UIAxes5 = uiaxes(app.ImagePanel);
            title(app.UIAxes5, 'TGFB Gradient (uM)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.ImagePanel);
            title(app.UIAxes4, 'NCad v. ECad')
            xlabel(app.UIAxes4, 'ECad')
            ylabel(app.UIAxes4, 'NCad')
            app.UIAxes3 = uiaxes(app.ImagePanel);
            title(app.UIAxes3, 'EMT Concentration')
            xlabel(app.UIAxes3, 'Concentration (uM)')
            ylabel(app.UIAxes3, 'Time (hr)')
            app.UIAxes6 = uiaxes(app.ImagePanel);
            title(app.UIAxes6, 'Diffusion Coefficient Values')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.RunTimeLabel = uilabel(app.ImagePanel);
            app.RunTimeLabel.Text = 'Run Time: ';
            app.RunsTab = uitab(app.TabGroup);
            app.RunsTab.Title = 'Runs';
            app.UITable = uitable(app.RunsTab);
            app.UITable.ColumnName = {'Cell Count + EMT Ratios'; 'Cell Location (On/In/Off Initial Radius)'; 'ECadherin'; 'NCadherin'; 'snai1_t'; 'Snail1'; 'zeb1_t'; 'Zeb1'; 'miR200'; 'miR34'; 'Avg TGFB'; 'Apical Avg TGFB'; 'Basal Avg TGFB'; 'Apical sum TGFB'; 'Basal sum TGFB'; 'Avg DTGFB'; 'Apical avg Dtgfb'; 'Basal Avg Dtgfb'; 'Apical Sum Dtgfb'; 'Basal Sum Dtgfb'; 'Distance Moved'; '# Times Moved'; 'CS Area'; 'Lumen Area'; 'CS Cell Count'; 'Major Diameter'; 'Minor Diameter'; 'Volume'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.CellOutputsTab = uitab(app.TabGroup);
            app.CellOutputsTab.Title = 'Cell Outputs';
            app.UITable2 = uitable(app.CellOutputsTab);
            app.UITable2.ColumnName = {'Cells'; 'EMT State'; 'ECadherin'; 'NCadherin'; 'Snail1'; 'Zeb1'; 'miR34'; 'miR200'; 'TGFB1 Concentration'; 'Dtgfb Value'; 'Times Moved'};
            app.UITable2.RowName = {};
            app.DMulticellularABMModelofEMTLabel = uilabel(app.RightPanel);
            app.DMulticellularABMModelofEMTLabel.Text = '3D Multicellular ABM Model of EMT';
            app.RunCountLabel = uilabel(app.RightPanel);
            app.RunCountLabel.Text = 'Run Count: ';
            % Show the figure after all components are created
            app.EMTModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMTcode_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMTModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMTModelUIFigure)
        end
    end
end