classdef EMTcode_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMTModelUIFigure                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        PreviewButton                   matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        TimePanel                       matlab.ui.container.Panel
        TimeIntervalhrEditField         matlab.ui.control.NumericEditField
        TimeIntervalhrEditFieldLabel    matlab.ui.control.Label
        FinalTimehrEditField            matlab.ui.control.NumericEditField
        FinalTimehrEditFieldLabel       matlab.ui.control.Label
        DataSelectionLabel              matlab.ui.control.Label
        Panel2_4                        matlab.ui.container.Panel
        IfmultiplecelltypeswerechosenLabel  matlab.ui.control.Label
        FibroblastsEditField            matlab.ui.control.EditField
        FibroblastsEditFieldLabel       matlab.ui.control.Label
        MorphologyDropDown              matlab.ui.control.DropDown
        MorphologyDropDownLabel         matlab.ui.control.Label
        CellTypesDropDown               matlab.ui.control.DropDown
        CellTypesDropDownLabel          matlab.ui.control.Label
        Panel2_2                        matlab.ui.container.Panel
        InitialDtgfbCoefficientEditField  matlab.ui.control.NumericEditField
        InitialDtgfbCoefficientEditFieldLabel  matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        ExogeneousTGFBEditField         matlab.ui.control.NumericEditField
        ExogeneousTGFBEditFieldLabel    matlab.ui.control.Label
        TGFBSignalingDropDown           matlab.ui.control.DropDown
        TGFBSignalingDropDown_2Label    matlab.ui.control.Label
        SignalingIntervalhrEditField    matlab.ui.control.NumericEditField
        SignalingIntervalhrEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        DMulticellularABMModelofEMTLabel  matlab.ui.control.Label
        SavingGraphOutputsPanel         matlab.ui.container.Panel
        SaveasButtonGroup               matlab.ui.container.ButtonGroup
        TosaveasagifmakesurethisischeckedbeforehittingRUNLabel  matlab.ui.control.Label
        NoneButton                      matlab.ui.control.RadioButton
        gifButton                       matlab.ui.control.RadioButton
        tifButton                       matlab.ui.control.RadioButton
        jpgButton                       matlab.ui.control.RadioButton
        ExportImageButton               matlab.ui.control.Button
        ImageNameEditField              matlab.ui.control.EditField
        ImageNameLabel                  matlab.ui.control.Label
        SavingTableOutputsPanel         matlab.ui.container.Panel
        ExportDataButton                matlab.ui.control.Button
        IncludefiletagxlsxorcsvLabel    matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        AppendtoexistingdatasheetCheckBox  matlab.ui.control.CheckBox
        RepeatRunsPanel                 matlab.ui.container.Panel
        RunsEditField                   matlab.ui.control.NumericEditField
        RunsEditFieldLabel              matlab.ui.control.Label
        RunCountLabel                   matlab.ui.control.Label
        DoyouwanttorunmultipleCheckBox  matlab.ui.control.CheckBox
        SavingDataResultsLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GraphsTab                       matlab.ui.container.Tab
        ImagePanel                      matlab.ui.container.Panel
        RunTimeLabel                    matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RunsTab                         matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        CellOutputsTab                  matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % Declare properties
        CellState     = DefineCells();
        Param         = RunParam();
        OutputResults = [];    
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Utility function to initialize a plot
            
            % Initially assume that all data will be displayed and then,
            %  subsequently, filter the data based on the controls            
            TGFB            = 0;
            CellTypes       = 'Epithelial Only';
            Morphology      = 0;
            FibroblastCount = 0;
            Dtgfb0          = 38;
            Tfinal          = 1; 
            t               = 1;

           % Setting up Exogeneous TGFB1 variable
            n      = app.Param.n;            
            Ctgfb  = TGFB*ones(n,n,n); % TGFB concentration gradient
            
            % Setting up Diffusion Coefficients/ ECM representation
            Dtgfb  = Dtgfb0*ones(n,n,n);  % Diffusion values matrix (nxnxn)
                       
            [app.CellState, Ctgfb, Dtgfb, Cstate] = ...
                CellMorphology(app.CellState, app.Param, Ctgfb, Dtgfb, ...
                Morphology, CellTypes, FibroblastCount);
        
            plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb,...
                TGFB, Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3,...
                app.UIAxes4, app.UIAxes5, app.UIAxes6);
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EMTModelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {825, 825};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PreviewButton
        function PreviewBtnPushed(app, event)
% Update Graphs based on changes to Variables
app.CellState   = DefineCells(); % Reset workspace
app.Param.TGFB  = app.ExogeneousTGFBEditField.Value;
app.Param.TGFBSignalType  = app.TGFBSignalingDropDown.Value;
app.Param.TGFBSignalInt   = app.SignalingIntervalhrEditField.Value;

app.Param.Dtgfb0          = app.InitialDtgfbCoefficientEditField.Value;
            
app.Param.CellTypes       = app.CellTypesDropDown.Value;
app.Param.Morphology      = app.MorphologyDropDown.Value;
app.Param.FibroblastCount = str2num(app.FibroblastsEditField.Value);
app.Param.Tfinal          = app.FinalTimehrEditField.Value; 
app.Param.Tint            = app.TimeIntervalhrEditField.Value;

t               = 1;

% Setting up Exogeneous TGFB1 variable
n               = app.Param.n;            
Ctgfb           = app.Param.TGFB*ones(n,n,n);  % TGFB concentration gradient

% Setting up Diffusion Coefficients/ ECM representation
Dtgfb           = app.Param.Dtgfb0*ones(n,n,n); 
        % Diffusion values matrix (nxnxn)
           
[app.CellState, Ctgfb, Dtgfb, Cstate] = CellMorphology(app.CellState,...
    app.Param, Ctgfb, Dtgfb, app.Param.Morphology, ...
    app.Param.CellTypes, app.Param.FibroblastCount);


plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
    app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3,...
    app.UIAxes4, app.UIAxes5, app.UIAxes6);

app.RunTimeLabel.Text = ['Run Time: ', num2str(t-1), ' hr'];
        end

        % Button pushed function: RunButton
        function RunBtnPushed(app, event)
% Checking Button Inputs:

% Do you want to run multiple simulations using the same parameters?
if app.DoyouwanttorunmultipleCheckBox.Value == 1 || app.RunsEditField.Value > 1
    Nruns = app.RunsEditField.Value;
    app.Param.Nruns = Nruns;
else
    app.Param.Nruns = 1;
end

DataOutput = [];
tic

% Starting the run:
for incr = 1:app.Param.Nruns

% Update Graphs based on changes to Variables
app.CellState   = DefineCells(); % Reset workspace

app.Param.TGFB            = app.ExogeneousTGFBEditField.Value;
app.Param.TGFBSignalType  = app.TGFBSignalingDropDown.Value;
app.Param.TGFBSignalInt   = app.SignalingIntervalhrEditField.Value;

app.Param.Dtgfb0          = app.InitialDtgfbCoefficientEditField.Value;
            
app.Param.CellTypes       = app.CellTypesDropDown.Value;
app.Param.Morphology      = app.MorphologyDropDown.Value;
app.Param.FibroblastCount = str2double(app.FibroblastsEditField.Value);

app.Param.Tfinal          = app.FinalTimehrEditField.Value; 
app.Param.Tint            = app.TimeIntervalhrEditField.Value;
t               = 1;
dt              = 1; 

% Setting up Exogeneous TGFB1 variable
n       = app.Param.n;            
Ctgfb   = app.Param.TGFB*ones(n,n,n);  % TGFB concentration gradient

% Setting up Diffusion Coefficients/ ECM representation
Dtgfb   = app.Param.Dtgfb0*ones(n,n,n);      % Diffusion values matrix (nxnxn)
           
[app.CellState, Ctgfb, Dtgfb, Cstate] = CellMorphology(app.CellState,...
    app.Param, Ctgfb, Dtgfb, app.Param.Morphology, ...
    app.Param.CellTypes, app.Param.FibroblastCount);

C0tgfb  = Ctgfb; % initial TGFB distribution

if app.Param.Nruns == 1
    plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
        app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3, ...
        app.UIAxes4, app.UIAxes5, app.UIAxes6);
    
    if app.gifButton.Value == 1
       
       ImageName = app.ImageNameEditField.Value;

       if isempty(app.ImageNameEditField.Value)

           uialert(app.EMTModelUIFigure, ...
               "Image Name not defined, set to Default", "Saving Image Output");
           break;

       else

        ImageTag = '.gif';
        ImageName = [ImageName, ImageTag];
        exportgraphics(app.ImagePanel, ImageName);
       end

    end
end

% Start time steps

for t = 2:dt:app.Param.Tfinal

    switch app.Param.TGFBSignalType

        case 'Removed'

           if t > app.Param.TGFBSignalInt
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = 0;
                            end
                        end
                    end
                end
            end
                        
        case 'Pulsed'

           if rem(t, app.Param.TGFBSignalInt*2) == 0
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = Ctgfb(i,j,k) + app.Param.TGFB;
                            end
                        end
                    end
               end

           elseif rem(t, app.Param.TGFBSignalInt) == 0 && rem(t, app.Param.TGFBSignalInt*2)~= 0

               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = 0;
                            end
                        end
                    end
               end
           end
    
        case 'Increasing Interval'

           if rem(t, app.Param.TGFBSignalInt) == 0
               for i = 1:n
                    for j = 1:n
                        for k = 1:n
                            if Cstate(i,j,k) == 0
                                Ctgfb(i,j,k) = Ctgfb(i,j,k) + app.Param.TGFB;
                            end
                        end
                    end
               end
           end
           
        case 'Constant'
            
     end

    Cmoved    = zeros(n,n,n); % monitor movements of cells + update in system
    
    [app.CellState, Cstate, Ctgfb, Dtgfb] = ...
        EMTStateChange(app.CellState, app.Param, Cstate, Ctgfb, ...
        Dtgfb, t, app.Param.Tfinal, dt);
    
    % Update Cell Proliferation + Migration
    flag     = 0; 
    v        = 1; 
    numCells = length(app.CellState.state);
    
    while flag == 0
        i = app.CellState.Position(v,4); 
        j = app.CellState.Position(v,5);  
        k = app.CellState.Position(v,6); 
        
        if Cmoved(i,j,k) == 0 && app.CellState.state(v) > 0

            [app.CellState, Cstate, Cmoved, Ctgfb, Dtgfb] = ...
                CellBehavior(app.CellState, app.Param, Cstate, ...
                i, j, k, v, Ctgfb, t, Cmoved, Dtgfb);  
        end
        
        v        = v + 1; 
        numCells = length(app.CellState.state);
        
        if v >= numCells
            flag = 1;
        end    
    end
   
    % Update TGFB Diffusion
    Ctgfb    = TGFBDiffusion(app.Param, Ctgfb, app.Param.TGFB, Dtgfb);
    
    % Update Population Cell Concentrations + Values
    eInd     = find(app.CellState.state < 4);

    app.CellState.AvgEcad(t)  = mean(app.CellState.conc(eInd, 7));
    app.CellState.AvgNcad(t)  = mean(app.CellState.Ncad(eInd,2));
    
    app.CellState.AvgSnail(t) = mean(app.CellState.conc(eInd, 2)); 
    app.CellState.AvgZeb1(t)  = mean(app.CellState.conc(eInd, 5));
    
    app.CellState.AvgR200(t)  = mean(app.CellState.conc(eInd, 6));
    app.CellState.AvgR34(t)   = mean(app.CellState.conc(eInd, 3));

    app.CellState.AvgTGFB(t)      = mean(app.CellState.Ctgfb(eInd));
    app.CellState.AvgDtgfb(t)     = mean(app.CellState.Dcell(eInd));
    
    % Finding CS area and diameters of spheroid
    Imgdim = sum(Cstate); Imgdim = squeeze(Imgdim); Imgdim = imfill(Imgdim);
    Imgdim2 = permute(Imgdim, [1 3 2]); 
    Imgdim2 = reshape(Imgdim,[],size(Imgdim,2),1);
    Imgdim2 = imbinarize(Imgdim2);
    tempStat = regionprops(Imgdim2, 'Area', 'MajorAxisLength','MinorAxisLength');
    tempStat = struct2table(tempStat);
    [~, maxInd] = max(tempStat.Area);
    
    app.CellState.Carea(t) = tempStat.Area(maxInd);
    app.CellState.MajorD(t) = tempStat.MajorAxisLength(maxInd);
    app.CellState.MinorD(t) = tempStat.MinorAxisLength(maxInd);
   

    % Plotting Data:
    if app.Param.Nruns == 1 && rem(t, app.Param.Tint) == 0
        plotFigs(app.CellState, app.Param, Cstate, Ctgfb, Dtgfb, app.Param.TGFB, ...
            app.Param.Tfinal, t, app.UIAxes, app.UIAxes2, app.UIAxes3, ...
            app.UIAxes4, app.UIAxes5, app.UIAxes6);

        app.RunTimeLabel.Text = ['Run Time: ', num2str(t-1), ' hr'];
        
        % Saving Possible Video Frames
        if app.gifButton.Value == 1

            exportgraphics(app.ImagePanel, ImageName, 'Append', true);
        
        end

        pause(0.05);   
    end

end

% Showing Individual Cell Data:
CellTable = table((1:length(app.CellState.state))', ...
    app.CellState.state', app.CellState.conc(:,7), ...
    app.CellState.Ncad(:, 2), app.CellState.conc(:,2), ...
    app.CellState.conc(:,5), app.CellState.conc(:,3), ...
    app.CellState.conc(:,6), app.CellState.Ctgfb', ...
    app.CellState.Dcell', app.CellState.TimesMoved');

app.UITable2.Data = CellTable;

% showing summarized population data:
DataOutput        = SaveExcelResults(app.CellState, app.Param, Cstate, ...
    Ctgfb, Dtgfb, incr, DataOutput);

app.OutputResults = [struct2table(DataOutput)];

app.UITable.Data = app.OutputResults;

app.RunCountLabel.Text = ['Run Count: ', num2str(incr)];

end

app.Param.totRunTime = toc;

        end

        % Value changed function: AppendtoexistingdatasheetCheckBox
        function AppendBtnChecked(app, event)
            
            [FileName, FilePath] = uigetfile('.xlsx', '.csv');

            app.FileNameEditField.Value = FileName;
        end

        % Button pushed function: ExportDataButton
        function SaveDataOutput(app, event)

            FileName = app.FileNameEditField.Value;

            if app.AppendtoexistingdatasheetCheckBox.Value == 0
                XFileName = [FileName];

                writetable(app.OutputResults, XFileName, ...
                    'Sheet', 'Outputs', 'Range', 'A1');
                
                ParamT = struct2cell(app.Param);
         ParamNames = { '# voxels/edge','Cell Size',...
         'Outer Radii', 'Inner Radii', 'Dtgfb cell value', 'Ncad Max', ...
         'Proliferation Threshold', 'Prob Thresholds', ...
         'Epithelial ODE Conc(0)', 'Mesenchymal ODE Conc(0)', ...
         'TGFB exogeneous Conc(0)', 'TGFB Signaling Dynamics', ...
         'TGFB Signaling Interval', 'Dtgfb Extracellular Value', ...
         'Culture Model', 'Morphology', '# Fibroblasts', 'Tfinal',...
         'Time interval','Nruns', 'Processing Speed'};

                sheetParam = 'Run Parameters';
                writecell([ParamNames', ParamT], XFileName, ...
                    'Sheet', sheetParam, 'Range', 'A1');


            elseif app.AppendtoexistingdatasheetCheckBox.Value == 1
                AppendBtnChecked(app, event);
                XFileName = FileName;
                writetable(app.OutputResults, XFileName, 'append');

            end

        end

        % Button pushed function: ExportImageButton
        function saveImageOutput(app, event)
           % Saving end image output
           
           ImageName = app.ImageNameEditField.Value;
           

           if isempty(app.ImageNameEditField.Value)
               uialert(app.EMTModelUIFigure, "Image Name not defined", "Saving Image Output");
           
           end


           if app.NoneButton.Value == 1
               uialert(app.EMTModelUIFigure, "Please choose an image extension", "Saving Image Output");
               return

           elseif app.tifButton.Value == 1
              ImageTag = '.tif';

           elseif app.jpgButton.Value == 1
               ImageTag = '.jpg';

           end

              ImageName = [ImageName, ImageTag];

              exportgraphics(app.ImagePanel, ImageName);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMTModelUIFigure = uifigure('Visible', 'off');
            app.EMTModelUIFigure.Name = 'EMTCode_App';
            app.EMTModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EMTModelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'TGFB Signaling';
            app.SignalingIntervalhrEditFieldLabel = uilabel(app.Panel2);
            app.SignalingIntervalhrEditFieldLabel.Text = 'Signaling Interval (hr)';
            app.SignalingIntervalhrEditField = uieditfield(app.Panel2, 'numeric');
            app.SignalingIntervalhrEditField.Limits = [0 Inf];
            app.TGFBSignalingDropDown_2Label = uilabel(app.Panel2);
            app.TGFBSignalingDropDown_2Label.Text = 'TGFB Signaling';
            app.TGFBSignalingDropDown = uidropdown(app.Panel2);
            app.TGFBSignalingDropDown.Items = {'Constant', 'Removed', 'Pulsed', 'Increasing Interval'};
            app.TGFBSignalingDropDown.Value = 'Constant';
            app.ExogeneousTGFBEditFieldLabel = uilabel(app.Panel2);
            app.ExogeneousTGFBEditFieldLabel.Text = 'Exogeneous TGFB';
            app.ExogeneousTGFBEditField = uieditfield(app.Panel2, 'numeric');
            app.ExogeneousTGFBEditField.Limits = [0 10];
            app.Panel2_2 = uipanel(app.LeftPanel);
            app.Panel2_2.Title = 'ECM Modeling';
            app.InitialDtgfbCoefficientEditFieldLabel = uilabel(app.Panel2_2);
            app.InitialDtgfbCoefficientEditFieldLabel.Text = 'Initial Dtgfb Coefficient';
            app.InitialDtgfbCoefficientEditField = uieditfield(app.Panel2_2, 'numeric');
            app.InitialDtgfbCoefficientEditField.Limits = [0 38];
            app.InitialDtgfbCoefficientEditField.Value = 38;
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Cell Morphology';
            app.CellTypesDropDownLabel = uilabel(app.Panel2_4);
            app.CellTypesDropDownLabel.Text = 'Cell Types';
            app.CellTypesDropDown = uidropdown(app.Panel2_4);
            app.CellTypesDropDown.Items = {'Epithelial Only', 'Embedded', 'Overlay'};
            app.CellTypesDropDown.Value = 'Epithelial Only';
            app.MorphologyDropDownLabel = uilabel(app.Panel2_4);
            app.MorphologyDropDownLabel.Text = 'Morphology';
            app.MorphologyDropDown = uidropdown(app.Panel2_4);
            app.MorphologyDropDown.Items = {'Spheroid', 'Straight Tube (Vertical)', 'Straight Tube (Horizontal)', 'Curved Tube'};
            app.MorphologyDropDown.ItemsData = [0 1 2 3];
            app.MorphologyDropDown.Value = 0;
            app.FibroblastsEditFieldLabel = uilabel(app.Panel2_4);
            app.FibroblastsEditFieldLabel.Text = '# Fibroblasts';
            app.FibroblastsEditField = uieditfield(app.Panel2_4, 'text');
            app.FibroblastsEditField.Placeholder = '0';
            app.FibroblastsEditField.Value = '0';
            app.IfmultiplecelltypeswerechosenLabel = uilabel(app.Panel2_4);
            app.IfmultiplecelltypeswerechosenLabel.Text = '** If multiple cell types were chosen';
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.TimePanel = uipanel(app.LeftPanel);
            app.TimePanel.Title = 'Time';
            app.FinalTimehrEditFieldLabel = uilabel(app.TimePanel);
            app.FinalTimehrEditFieldLabel.Text = 'Final Time (hr)';
            app.FinalTimehrEditField = uieditfield(app.TimePanel, 'numeric');
            app.FinalTimehrEditField.Limits = [1 Inf];
            app.FinalTimehrEditField.Value = 1;
            app.TimeIntervalhrEditFieldLabel = uilabel(app.TimePanel);
            app.TimeIntervalhrEditFieldLabel.Text = 'Time Interval (hr)';
            app.TimeIntervalhrEditField = uieditfield(app.TimePanel, 'numeric');
            app.TimeIntervalhrEditField.Limits = [1 Inf];
            app.TimeIntervalhrEditField.Value = 1;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunButton.Text = 'Run';
            app.PreviewButton = uibutton(app.LeftPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewBtnPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'cellwitheyes.JPG';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.ImagePanel = uipanel(app.GraphsTab);
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'Cell Morphology')
            xlabel(app.UIAxes, 'X (um)')
            ylabel(app.UIAxes, 'Y (um)')
            zlabel(app.UIAxes, 'Z (um)')
            app.UIAxes.XTick = [0 32];
            app.UIAxes2 = uiaxes(app.ImagePanel);
            title(app.UIAxes2, 'EMT Population')
            xlabel(app.UIAxes2, 'Time (hr)')
            app.UIAxes5 = uiaxes(app.ImagePanel);
            title(app.UIAxes5, 'TGFB Gradient')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.ImagePanel);
            title(app.UIAxes4, 'NCad v. ECad')
            xlabel(app.UIAxes4, 'ECad')
            ylabel(app.UIAxes4, 'NCad')
            app.UIAxes3 = uiaxes(app.ImagePanel);
            title(app.UIAxes3, 'EMT Concentration')
            xlabel(app.UIAxes3, 'Concentration (uM)')
            ylabel(app.UIAxes3, 'Time (hr)')
            app.UIAxes6 = uiaxes(app.ImagePanel);
            title(app.UIAxes6, 'Diffusion Coefficient Values')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.RunTimeLabel = uilabel(app.ImagePanel);
            app.RunTimeLabel.Text = 'Run Time: ';
            app.RunsTab = uitab(app.TabGroup);
            app.RunsTab.Title = 'Runs';
            app.UITable = uitable(app.RunsTab);
            app.UITable.ColumnName = {'Cell Count + EMT Ratios'; 'Cell Location (On/In/Off Initial Radius)'; 'ECadherin'; 'NCadherin'; 'snai1_t'; 'Snail1'; 'zeb1_t'; 'Zeb1'; 'miR200'; 'miR34'; 'Cell TGFB1'; 'Cell Dtgfb Value'; 'Distance Moved'; '# Times Moved'; 'Cross-Sectional Area'; 'Major Diameter'; 'Minor Diameter'; 'Total TGFB1'; 'Total Dtgfb Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.CellOutputsTab = uitab(app.TabGroup);
            app.CellOutputsTab.Title = 'Cell Outputs';
            app.UITable2 = uitable(app.CellOutputsTab);
            app.UITable2.ColumnName = {'Cells'; 'Cell State'; 'ECadherin'; 'NCadherin'; 'Snail1'; 'Zeb1'; 'miR34'; 'miR200'; 'TGFB1 Concentration'; 'Dtgfb Value.Tmes Moved'};
            app.UITable2.RowName = {};
            app.SavingDataResultsLabel = uilabel(app.RightPanel);
            app.SavingDataResultsLabel.Text = 'Saving Data Results';
            app.RepeatRunsPanel = uipanel(app.RightPanel);
            app.RepeatRunsPanel.Title = 'Repeat Runs';
            app.DoyouwanttorunmultipleCheckBox = uicheckbox(app.RepeatRunsPanel);
            app.DoyouwanttorunmultipleCheckBox.Text = '   Do you want to run multiple?';
            app.RunCountLabel = uilabel(app.RepeatRunsPanel);
            app.RunCountLabel.Text = 'Run Count: ';
            app.RunsEditFieldLabel = uilabel(app.RepeatRunsPanel);
            app.RunsEditFieldLabel.Text = '# Runs';
            app.RunsEditField = uieditfield(app.RepeatRunsPanel, 'numeric');
            app.RunsEditField.Limits = [1 Inf];
            app.RunsEditField.Placeholder = '1';
            app.RunsEditField.Value = 1;
            app.SavingTableOutputsPanel = uipanel(app.RightPanel);
            app.SavingTableOutputsPanel.Title = 'Saving Table Outputs';
            app.AppendtoexistingdatasheetCheckBox = uicheckbox(app.SavingTableOutputsPanel);
            app.AppendtoexistingdatasheetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppendBtnChecked, true);
            app.AppendtoexistingdatasheetCheckBox.Text = 'Append to existing data sheet';
            app.FileNameEditFieldLabel = uilabel(app.SavingTableOutputsPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SavingTableOutputsPanel, 'text');
            app.IncludefiletagxlsxorcsvLabel = uilabel(app.SavingTableOutputsPanel);
            app.IncludefiletagxlsxorcsvLabel.Text = '** Include file tag (.xlsx or .csv)';
            app.ExportDataButton = uibutton(app.SavingTableOutputsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataOutput, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SavingGraphOutputsPanel = uipanel(app.RightPanel);
            app.SavingGraphOutputsPanel.Title = 'Saving Graph Outputs';
            app.ImageNameLabel = uilabel(app.SavingGraphOutputsPanel);
            app.ImageNameLabel.Text = 'Image Name: ';
            app.ImageNameEditField = uieditfield(app.SavingGraphOutputsPanel, 'text');
            app.ExportImageButton = uibutton(app.SavingGraphOutputsPanel, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImageOutput, true);
            app.ExportImageButton.Text = 'Export Image';
            app.SaveasButtonGroup = uibuttongroup(app.SavingGraphOutputsPanel);
            app.SaveasButtonGroup.Title = 'Save as:';
            app.jpgButton = uiradiobutton(app.SaveasButtonGroup);
            app.jpgButton.Text = '.jpg';
            app.tifButton = uiradiobutton(app.SaveasButtonGroup);
            app.tifButton.Text = '.tif';
            app.gifButton = uiradiobutton(app.SaveasButtonGroup);
            app.gifButton.Text = '.gif';
            app.NoneButton = uiradiobutton(app.SaveasButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.TosaveasagifmakesurethisischeckedbeforehittingRUNLabel = uilabel(app.SaveasButtonGroup);
            app.TosaveasagifmakesurethisischeckedbeforehittingRUNLabel.Text = '** To save as a .gif, make sure this is checked before hitting RUN';
            app.DMulticellularABMModelofEMTLabel = uilabel(app.RightPanel);
            app.DMulticellularABMModelofEMTLabel.Text = '3D Multicellular ABM Model of EMT';
            % Show the figure after all components are created
            app.EMTModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMTcode_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMTModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMTModelUIFigure)
        end
    end
end